classdef AudioReceiver < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        SelectStegoAudioButton          matlab.ui.control.Button
        stego_text                      matlab.ui.control.EditField
        ExtractButton                   matlab.ui.control.Button
        DIGISIGNEditFieldLabel          matlab.ui.control.Label
        DIGISIGNEditField               matlab.ui.control.EditField
        CIPHEREditFieldLabel            matlab.ui.control.Label
        CIPHEREditField                 matlab.ui.control.EditField
        DecrypttheValueofXButton        matlab.ui.control.Button
        ValueofYEditFieldLabel          matlab.ui.control.Label
        ValueofYEditField               matlab.ui.control.NumericEditField
        decrypted_x                     matlab.ui.control.NumericEditField
        DecrypttoCBCMessageButton       matlab.ui.control.Button
        cbc_message_decrypted           matlab.ui.control.EditField
        DecrypttheMessageDataButton     matlab.ui.control.Button
        message_data                    matlab.ui.control.EditField
        DigestofDecryptedMessageButton  matlab.ui.control.Button
        message_data_digest             matlab.ui.control.EditField
        DigestfromDIGISIGNButton        matlab.ui.control.Button
        digi_sign_digest                matlab.ui.control.EditField
        VerifyIntegrityButton           matlab.ui.control.Button
        integrity_check                 matlab.ui.control.EditField
        authentication_verified         matlab.ui.control.EditField
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: SelectStegoAudioButton
        function SelectStegoAudioButtonPushed(app, event)
            file = uigetfile('*.wav','Select the Stego Audio!');
            app.stego_text.Value = file;
        end

        % Button pushed function: ExtractButton
        function ExtractButtonPushed(app, event)
            msg = lsb_dec(app.stego_text.Value,'mypass');
            fid = fopen('PVD_Extract.txt','w');
            fun = fprintf(fid,'%c',msg);
            fclose(fid);
            fid = fopen('PVD_Extract.txt','r');
            A = fscanf(fid,'%d');
            B = transpose(A);
            digital_signature2_demo = B(1:128);
            digital_signature2 = int32(digital_signature2_demo);
            generated_Y2 = B(129);
            len_cipher_text = B(136);
            ciphertext2 = B(154:(154 + len_cipher_text - 1));
            app.DIGISIGNEditField.Value = char(digital_signature2);
            app.CIPHEREditField.Value = char(ciphertext2);
            app.ValueofYEditField.Value = double(generated_Y2);
        end

        % Button pushed function: DecrypttheValueofXButton
        function DecrypttheValueofXButtonPushed(app, event)
            fid = fopen('PVD_Extract.txt','r');
            A = fscanf(fid,'%d');
            B = transpose(A);
            Modulus = B(130);
            PrivateExponent = B(132);
            RestoredMessage_demo = RSA_Decrypt(int32(Modulus), int32(PrivateExponent), int32(app.ValueofYEditField.Value));
            RestoredMessage = RestoredMessage_demo - 48;
            app.decrypted_x.Value = double(RestoredMessage);
        end

        % Button pushed function: DecrypttoCBCMessageButton
        function DecrypttoCBCMessageButtonPushed(app, event)
            fid = fopen('PVD_Extract.txt','r');
            A = fscanf(fid,'%d');
            B = transpose(A);
            len_cipher_text = B(136);
            ciphertext2 = B(154:(154 + len_cipher_text - 1));
            fclose(fid);
            recovery = aes_simple_decryption(char(ciphertext2), char(string(app.decrypted_x.Value)));
            app.cbc_message_decrypted.Value = char(recovery);
        end

        % Button pushed function: DecrypttheMessageDataButton
        function DecrypttheMessageDataButtonPushed(app, event)
            recovery = app.cbc_message_decrypted.Value;
            fid = fopen('PVD_Extract.txt','r');
            A = fscanf(fid,'%d');
            B = transpose(A);
            result_init_vector = B(138:153);
            len_plain_demo = B(137);
            iterate = length(char(recovery))/16;
            
            b = 1;
            for i = 1:iterate
                pub_image{i} = char(recovery((b):(b+(16-1))));
                b = b + 16;
            end
            
            recov_ = [];
            recov = [];
            recov_{1} = aes_simple_decryption(char(pub_image{1}),char(string(app.decrypted_x.Value)));
            recov{1} = bitxor(int32(recov_{1}),int32(result_init_vector));
            if iterate >= 2
                for j = 2:iterate
                    recov_{j} = aes_simple_decryption(char(pub_image{j}),char(string(app.decrypted_x.Value)));
                    recov{j} = bitxor(int32(recov_{j}),int32(pub_image{j-1}));
                end
            end
            reco = strcat(recov{1:iterate});
            plaintext_recov = char(reco);
            app.message_data.Value = plaintext_recov(1:len_plain_demo);
        end

        % Button pushed function: DigestofDecryptedMessageButton
        function DigestofDecryptedMessageButtonPushed(app, event)
%             recovery = app.cbc_message_decrypted.Value;
%             fid = fopen('PVD_Extract.txt','r');
%             A = fscanf(fid,'%d');
%             B = transpose(A);
%             len_plain_demo = B(137);
%             plaintext_recov_ = recovery(1:len_plain_demo);
            digest = hash(char(app.cbc_message_decrypted.Value),'SHA-512');
            app.message_data_digest.Value = digest;
        end

        % Button pushed function: DigestfromDIGISIGNButton
        function DigestfromDIGISIGNButtonPushed(app, event)
            fid = fopen('PVD_Extract.txt','r');
            A = fscanf(fid,'%d');
            B = transpose(A);
            Modulus2 = B(133);
            PublicExponent2 = B(134);
            digital_signature2_demo = B(1:128);
            fclose(fid);
            digital_signature2 = int32(digital_signature2_demo);
            digest3_demo = RSA_Decrypt(Modulus2, PublicExponent2, digital_signature2);
            digest3 = char(digest3_demo);
            app.digi_sign_digest.Value = digest3;
            app.authentication_verified.Value = 'Authentication is Verified!';
        end

        % Button pushed function: VerifyIntegrityButton
        function VerifyIntegrityButtonPushed(app, event)
            digest1 = char(app.message_data_digest.Value);
            digest2 = char(app.digi_sign_digest.Value);
            if digest1 == digest2
                app.integrity_check.Value = 'Integrity is Verified!';
            else
                app.integrity_check.Value = 'Integrity is Not Verified!';
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.SelectStegoAudioButton = uibutton(app.UIFigure, 'push');
            app.SelectStegoAudioButton.ButtonPushedFcn = createCallbackFcn(app, @SelectStegoAudioButtonPushed, true);
            app.SelectStegoAudioButton.Text = 'Select Stego Audio';
            app.stego_text = uieditfield(app.UIFigure, 'text');
            app.ExtractButton = uibutton(app.UIFigure, 'push');
            app.ExtractButton.ButtonPushedFcn = createCallbackFcn(app, @ExtractButtonPushed, true);
            app.ExtractButton.Text = 'Extract';
            app.DIGISIGNEditFieldLabel = uilabel(app.UIFigure);
            app.DIGISIGNEditFieldLabel.Text = 'DIGI SIGN';
            app.DIGISIGNEditField = uieditfield(app.UIFigure, 'text');
            app.CIPHEREditFieldLabel = uilabel(app.UIFigure);
            app.CIPHEREditFieldLabel.Text = 'CIPHER';
            app.CIPHEREditField = uieditfield(app.UIFigure, 'text');
            app.DecrypttheValueofXButton = uibutton(app.UIFigure, 'push');
            app.DecrypttheValueofXButton.ButtonPushedFcn = createCallbackFcn(app, @DecrypttheValueofXButtonPushed, true);
            app.DecrypttheValueofXButton.Text = 'Decrypt the Value of X';
            app.ValueofYEditFieldLabel = uilabel(app.UIFigure);
            app.ValueofYEditFieldLabel.Text = 'Value of Y';
            app.ValueofYEditField = uieditfield(app.UIFigure, 'numeric');
            app.decrypted_x = uieditfield(app.UIFigure, 'numeric');
            app.DecrypttoCBCMessageButton = uibutton(app.UIFigure, 'push');
            app.DecrypttoCBCMessageButton.ButtonPushedFcn = createCallbackFcn(app, @DecrypttoCBCMessageButtonPushed, true);
            app.DecrypttoCBCMessageButton.Text = 'Decrypt to CBC Message';
            app.cbc_message_decrypted = uieditfield(app.UIFigure, 'text');
            app.DecrypttheMessageDataButton = uibutton(app.UIFigure, 'push');
            app.DecrypttheMessageDataButton.ButtonPushedFcn = createCallbackFcn(app, @DecrypttheMessageDataButtonPushed, true);
            app.DecrypttheMessageDataButton.Text = 'Decrypt the Message Data';
            app.message_data = uieditfield(app.UIFigure, 'text');
            app.DigestofDecryptedMessageButton = uibutton(app.UIFigure, 'push');
            app.DigestofDecryptedMessageButton.ButtonPushedFcn = createCallbackFcn(app, @DigestofDecryptedMessageButtonPushed, true);
            app.DigestofDecryptedMessageButton.Text = 'Digest of Decrypted Message';
            app.message_data_digest = uieditfield(app.UIFigure, 'text');
            app.DigestfromDIGISIGNButton = uibutton(app.UIFigure, 'push');
            app.DigestfromDIGISIGNButton.ButtonPushedFcn = createCallbackFcn(app, @DigestfromDIGISIGNButtonPushed, true);
            app.DigestfromDIGISIGNButton.Text = 'Digest from DIGI SIGN';
            app.digi_sign_digest = uieditfield(app.UIFigure, 'text');
            app.VerifyIntegrityButton = uibutton(app.UIFigure, 'push');
            app.VerifyIntegrityButton.ButtonPushedFcn = createCallbackFcn(app, @VerifyIntegrityButtonPushed, true);
            app.VerifyIntegrityButton.Text = 'Verify Integrity';
            app.integrity_check = uieditfield(app.UIFigure, 'text');
            app.authentication_verified = uieditfield(app.UIFigure, 'text');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = AudioReceiver

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end